classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OKButton                        matlab.ui.control.Button
        GeneralParameterPanel           matlab.ui.container.Panel
        AngleofAttackdegEditFieldLabel  matlab.ui.control.Label
        AngleofAttackdegEditField       matlab.ui.control.NumericEditField
        TimeDurationsEditFieldLabel     matlab.ui.control.Label
        TimeDurationsEditField          matlab.ui.control.NumericEditField
        NumberofControlpointsEditFieldLabel  matlab.ui.control.Label
        NumberofControlpointsEditField  matlab.ui.control.NumericEditField
        LeadingEdgeVortexSheddingSwitchLabel  matlab.ui.control.Label
        LeadingEdgeVortexSheddingSwitch  matlab.ui.control.Switch
        ChordLengthmEditFieldLabel      matlab.ui.control.Label
        ChordLengthmEditField           matlab.ui.control.NumericEditField
        SurgeProfilePanel               matlab.ui.container.Panel
        FinalSpeedmsEditFieldLabel      matlab.ui.control.Label
        FinalSpeedmsEditField           matlab.ui.control.NumericEditField
        SurgeProfileDropDownLabel       matlab.ui.control.Label
        SurgeProfileDropDown            matlab.ui.control.DropDown
        Accelarationms2EditFieldLabel   matlab.ui.control.Label
        Accelarationms2EditField        matlab.ui.control.NumericEditField
        PitchingProfilePanel            matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        NoneTab                         matlab.ui.container.Tab
        ConstantTab                     matlab.ui.container.Tab
        PitchingPointSliderLabel        matlab.ui.control.Label
        PitchingPointSlider             matlab.ui.control.Slider
        OmegadegsEditFieldLabel         matlab.ui.control.Label
        OmegadegsEditField              matlab.ui.control.NumericEditField
        SinusoidalTab                   matlab.ui.container.Tab
        PitchingPointSlider_2Label      matlab.ui.control.Label
        PitchingPointSlider_2           matlab.ui.control.Slider
        PitchFrequencydegsEditFieldLabel  matlab.ui.control.Label
        PitchFrequencydegsEditField     matlab.ui.control.NumericEditField
        PitchAmplitudedegEditFieldLabel  matlab.ui.control.Label
        PitchAmplitudedegEditField      matlab.ui.control.NumericEditField
        ClosedloopTab                   matlab.ui.container.Tab
        PitchingPointSlider_3Label      matlab.ui.control.Label
        PitchingPointSlider_3           matlab.ui.control.Slider
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        ProportionalGainEditField       matlab.ui.control.NumericEditField
        GustPanel                       matlab.ui.container.Panel
        GustSwitchLabel                 matlab.ui.control.Label
        GustSwitch                      matlab.ui.control.Switch
        GustWidthEditFieldLabel         matlab.ui.control.Label
        GustWidthEditField              matlab.ui.control.NumericEditField
        GustTypeDropDownLabel           matlab.ui.control.Label
        GustTypeDropDown                matlab.ui.control.DropDown
        InitialGustPositionmEditFieldLabel  matlab.ui.control.Label
        InitialGustPositionmEditField   matlab.ui.control.NumericEditField
        GREditFieldLabel                matlab.ui.control.Label
        GREditField                     matlab.ui.control.NumericEditField
        RiseSlopemsmEditFieldLabel      matlab.ui.control.Label
        RiseSlopemsmEditField           matlab.ui.control.NumericEditField
        RiseSlopemsmEditFieldLabel_2    matlab.ui.control.Label
        AnimationPlotaxisPanel          matlab.ui.container.Panel
        X_minmEditFieldLabel            matlab.ui.control.Label
        X_minmEditField                 matlab.ui.control.NumericEditField
        Y_minmEditFieldLabel            matlab.ui.control.Label
        Y_minmEditField                 matlab.ui.control.NumericEditField
        FlowfieldLabel                  matlab.ui.control.Label
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        X_maxmEditFieldLabel            matlab.ui.control.Label
        X_maxmEditField                 matlab.ui.control.NumericEditField
        Y_maxmEditFieldLabel            matlab.ui.control.Label
        Y_maxmEditField                 matlab.ui.control.NumericEditField
        LiftCoefficientLabel            matlab.ui.control.Label
        maxEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LeadingEdgeVortexSheddingSwitch
        function LeadingEdgeVortexSheddingSwitchValueChanged(app, event)
           
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            VortexModel(app.LeadingEdgeVortexSheddingSwitch.Value, app.TimeDurationsEditField.Value, app.NumberofControlpointsEditField.Value, ...
                app.AngleofAttackdegEditField.Value, app.SurgeProfileDropDown.Value, app.FinalSpeedmsEditField.Value,app.Accelarationms2EditField.Value, ...
                app.TypeDropDown.Value,app.PitchingPointSlider.Value, app.OmegadegsEditField.Value,app.GustSwitch.Value, ...
                app.GustWidthEditField.Value,app.GustTypeDropDown.Value,app.ChordLengthmEditField.Value,app.InitialGustPositionmEditField.Value, ...
                app.X_minmEditField.Value, app.X_maxmEditField.Value, app.Y_minmEditField.Value, app.Y_maxmEditField.Value, ...
                app.minEditField.Value, app.maxEditField.Value,app.GREditField.Value,app.PitchingPointSlider_2.Value,app.PitchFrequencydegsEditField.Value, ...
                app.PitchAmplitudedegEditField.Value, app.PitchingPointSlider_3.Value, app.ProportionalGainEditField.Value, app.RiseSlopemsmEditField.Value);
        end

        % Value changed function: AngleofAttackdegEditField
        function AngleofAttackdegEditFieldValueChanged(app, event)
            
        end

        % Value changed function: TimeDurationsEditField
        function TimeDurationsEditFieldValueChanged(app, event)
          
        end

        % Value changed function: NumberofControlpointsEditField
        function NumberofControlpointsEditFieldValueChanged(app, event)
   
        end

        % Value changed function: SurgeProfileDropDown
        function SurgeProfileDropDownValueChanged(app, event)
                    
        end

        % Value changed function: FinalSpeedmsEditField
        function FinalSpeedmsEditFieldValueChanged(app, event)
          
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
        
        end

        % Value changed function: PitchingPointSlider
        function PitchingPointSliderValueChanged(app, event)
        
        end

        % Value changed function: OmegadegsEditField
        function OmegadegsEditFieldValueChanged(app, event)
     
        end

        % Value changed function: GustSwitch
        function GustSwitchValueChanged(app, event)
         
        end

        % Value changed function: GustWidthEditField
        function GustWidthEditFieldValueChanged(app, event)
        
        end

        % Value changed function: GustTypeDropDown
        function GustTypeDropDownValueChanged(app, event)
       
        end

        % Value changed function: ChordLengthmEditField
        function ChordLengthmEditFieldValueChanged(app, event)
        
        end

        % Value changed function: Accelarationms2EditField
        function Accelarationms2EditFieldValueChanged(app, event)
       
        end

        % Value changed function: InitialGustPositionmEditField
        function InitialGustPositionmEditFieldValueChanged(app, event)
       
        end

        % Value changed function: X_minmEditField
        function X_minmEditFieldValueChanged(app, event)
        
        end

        % Value changed function: X_maxmEditField
        function X_maxmEditFieldValueChanged(app, event)
          
        end

        % Value changed function: Y_minmEditField
        function Y_minmEditFieldValueChanged(app, event)
          
        end

        % Value changed function: Y_maxmEditField
        function Y_maxmEditFieldValueChanged(app, event)
          
        end

        % Value changed function: minEditField
        function minEditFieldValueChanged(app, event)
         
        end

        % Value changed function: maxEditField
        function maxEditFieldValueChanged(app, event)
       
        end

        % Value changed function: GREditField
        function GREditFieldValueChanged(app, event)
            value = app.GREditField.Value;
            
        end

        % Value changed function: PitchingPointSlider_2
        function PitchingPointSlider_2ValueChanged(app, event)
            value = app.PitchingPointSlider_2.Value;
            
        end

        % Value changed function: PitchFrequencydegsEditField
        function PitchFrequencydegsEditFieldValueChanged(app, event)
            value = app.PitchFrequencydegsEditField.Value;
            
        end

        % Value changed function: PitchAmplitudedegEditField
        function PitchAmplitudedegEditFieldValueChanged(app, event)
            value = app.PitchAmplitudedegEditField.Value;
            
        end

        % Value changed function: ProportionalGainEditField
        function ProportionalGainEditFieldValueChanged(app, event)
            value = app.ProportionalGainEditField.Value;
            
        end

        % Value changed function: PitchingPointSlider_3
        function PitchingPointSlider_3ValueChanged(app, event)
            value = app.PitchingPointSlider_3.Value;
            
        end

        % Value changed function: RiseSlopemsmEditField
        function RiseSlopemsmEditFieldValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.GeneralParameterPanel = uipanel(app.UIFigure);
            app.GeneralParameterPanel.Title = 'General Parameter';
            app.AngleofAttackdegEditFieldLabel = uilabel(app.GeneralParameterPanel);
            app.AngleofAttackdegEditFieldLabel.Text = 'Angle of Attack, deg.';
            app.AngleofAttackdegEditField = uieditfield(app.GeneralParameterPanel, 'numeric');
            app.AngleofAttackdegEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofAttackdegEditFieldValueChanged, true);
            app.TimeDurationsEditFieldLabel = uilabel(app.GeneralParameterPanel);
            app.TimeDurationsEditFieldLabel.Text = 'Time Duration, s';
            app.TimeDurationsEditField = uieditfield(app.GeneralParameterPanel, 'numeric');
            app.TimeDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeDurationsEditFieldValueChanged, true);
            app.TimeDurationsEditField.Value = 5;
            app.NumberofControlpointsEditFieldLabel = uilabel(app.GeneralParameterPanel);
            app.NumberofControlpointsEditFieldLabel.Text = 'Number of Control points';
            app.NumberofControlpointsEditField = uieditfield(app.GeneralParameterPanel, 'numeric');
            app.NumberofControlpointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofControlpointsEditFieldValueChanged, true);
            app.NumberofControlpointsEditField.Value = 100;
            app.LeadingEdgeVortexSheddingSwitchLabel = uilabel(app.GeneralParameterPanel);
            app.LeadingEdgeVortexSheddingSwitchLabel.Text = 'Leading-Edge Vortex Shedding';
            app.LeadingEdgeVortexSheddingSwitch = uiswitch(app.GeneralParameterPanel, 'slider');
            app.LeadingEdgeVortexSheddingSwitch.ValueChangedFcn = createCallbackFcn(app, @LeadingEdgeVortexSheddingSwitchValueChanged, true);
            app.LeadingEdgeVortexSheddingSwitch.Value = 'On';
            app.ChordLengthmEditFieldLabel = uilabel(app.GeneralParameterPanel);
            app.ChordLengthmEditFieldLabel.Text = 'Chord Length, m';
            app.ChordLengthmEditField = uieditfield(app.GeneralParameterPanel, 'numeric');
            app.ChordLengthmEditField.ValueChangedFcn = createCallbackFcn(app, @ChordLengthmEditFieldValueChanged, true);
            app.ChordLengthmEditField.Value = 0.0762;
            app.SurgeProfilePanel = uipanel(app.UIFigure);
            app.SurgeProfilePanel.Title = 'Surge Profile';
            app.FinalSpeedmsEditFieldLabel = uilabel(app.SurgeProfilePanel);
            app.FinalSpeedmsEditFieldLabel.Text = 'Final Speed, m/s';
            app.FinalSpeedmsEditField = uieditfield(app.SurgeProfilePanel, 'numeric');
            app.FinalSpeedmsEditField.ValueChangedFcn = createCallbackFcn(app, @FinalSpeedmsEditFieldValueChanged, true);
            app.FinalSpeedmsEditField.Value = 0.08;
            app.SurgeProfileDropDownLabel = uilabel(app.SurgeProfilePanel);
            app.SurgeProfileDropDownLabel.Text = 'Surge Profile';
            app.SurgeProfileDropDown = uidropdown(app.SurgeProfilePanel);
            app.SurgeProfileDropDown.Items = {'Step Response', 'Linear Ramp', 'Trapezoidal'};
            app.SurgeProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SurgeProfileDropDownValueChanged, true);
            app.SurgeProfileDropDown.Value = 'Step Response';
            app.Accelarationms2EditFieldLabel = uilabel(app.SurgeProfilePanel);
            app.Accelarationms2EditFieldLabel.Text = 'Accelaration, m/s^2';
            app.Accelarationms2EditField = uieditfield(app.SurgeProfilePanel, 'numeric');
            app.Accelarationms2EditField.ValueChangedFcn = createCallbackFcn(app, @Accelarationms2EditFieldValueChanged, true);
            app.Accelarationms2EditField.Value = 0.1130069;
            app.PitchingProfilePanel = uipanel(app.UIFigure);
            app.PitchingProfilePanel.Title = 'Pitching Profile';
            app.TypeDropDownLabel = uilabel(app.PitchingProfilePanel);
            app.TypeDropDownLabel.Text = {'Type'; ''};
            app.TypeDropDown = uidropdown(app.PitchingProfilePanel);
            app.TypeDropDown.Items = {'None', 'Constant', 'Sinusoidal', 'Closed-loop'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'None';
            app.TabGroup = uitabgroup(app.PitchingProfilePanel);
            app.NoneTab = uitab(app.TabGroup);
            app.NoneTab.Title = 'None';
            app.ConstantTab = uitab(app.TabGroup);
            app.ConstantTab.Title = 'Constant';
            app.PitchingPointSliderLabel = uilabel(app.ConstantTab);
            app.PitchingPointSliderLabel.Text = 'Pitching Point';
            app.PitchingPointSlider = uislider(app.ConstantTab);
            app.PitchingPointSlider.Limits = [0 1];
            app.PitchingPointSlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PitchingPointSlider.MajorTickLabels = {'L.E.', '', '', '', 'T.E.'};
            app.PitchingPointSlider.ValueChangedFcn = createCallbackFcn(app, @PitchingPointSliderValueChanged, true);
            app.PitchingPointSlider.Value = 0.5;
            app.OmegadegsEditFieldLabel = uilabel(app.ConstantTab);
            app.OmegadegsEditFieldLabel.Text = 'Omega, deg/s';
            app.OmegadegsEditField = uieditfield(app.ConstantTab, 'numeric');
            app.OmegadegsEditField.ValueChangedFcn = createCallbackFcn(app, @OmegadegsEditFieldValueChanged, true);
            app.SinusoidalTab = uitab(app.TabGroup);
            app.SinusoidalTab.Title = 'Sinusoidal';
            app.PitchingPointSlider_2Label = uilabel(app.SinusoidalTab);
            app.PitchingPointSlider_2Label.Text = 'Pitching Point';
            app.PitchingPointSlider_2 = uislider(app.SinusoidalTab);
            app.PitchingPointSlider_2.Limits = [0 1];
            app.PitchingPointSlider_2.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PitchingPointSlider_2.MajorTickLabels = {'L.E.', '', '', '', 'T.E.'};
            app.PitchingPointSlider_2.ValueChangedFcn = createCallbackFcn(app, @PitchingPointSlider_2ValueChanged, true);
            app.PitchingPointSlider_2.Value = 0.5;
            app.PitchFrequencydegsEditFieldLabel = uilabel(app.SinusoidalTab);
            app.PitchFrequencydegsEditFieldLabel.Text = 'Pitch Frequency, deg/s';
            app.PitchFrequencydegsEditField = uieditfield(app.SinusoidalTab, 'numeric');
            app.PitchFrequencydegsEditField.ValueChangedFcn = createCallbackFcn(app, @PitchFrequencydegsEditFieldValueChanged, true);
            app.PitchFrequencydegsEditField.Value = 20;
            app.PitchAmplitudedegEditFieldLabel = uilabel(app.SinusoidalTab);
            app.PitchAmplitudedegEditFieldLabel.Text = 'Pitch Amplitude, deg';
            app.PitchAmplitudedegEditField = uieditfield(app.SinusoidalTab, 'numeric');
            app.PitchAmplitudedegEditField.ValueChangedFcn = createCallbackFcn(app, @PitchAmplitudedegEditFieldValueChanged, true);
            app.PitchAmplitudedegEditField.Value = 20;
            app.ClosedloopTab = uitab(app.TabGroup);
            app.ClosedloopTab.Title = 'Closed-loop';
            app.PitchingPointSlider_3Label = uilabel(app.ClosedloopTab);
            app.PitchingPointSlider_3Label.Text = 'Pitching Point';
            app.PitchingPointSlider_3 = uislider(app.ClosedloopTab);
            app.PitchingPointSlider_3.Limits = [0 1];
            app.PitchingPointSlider_3.MajorTicks = [0 0.25 0.5 0.75 1];
            app.PitchingPointSlider_3.MajorTickLabels = {'L.E.', '', '', '', 'T.E.'};
            app.PitchingPointSlider_3.ValueChangedFcn = createCallbackFcn(app, @PitchingPointSlider_3ValueChanged, true);
            app.PitchingPointSlider_3.Value = 0.5;
            app.ProportionalGainEditFieldLabel = uilabel(app.ClosedloopTab);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditField = uieditfield(app.ClosedloopTab, 'numeric');
            app.ProportionalGainEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldValueChanged, true);
            app.ProportionalGainEditField.Value = 50;
            app.GustPanel = uipanel(app.UIFigure);
            app.GustPanel.Title = 'Gust';
            app.GustSwitchLabel = uilabel(app.GustPanel);
            app.GustSwitchLabel.Text = 'Gust';
            app.GustSwitch = uiswitch(app.GustPanel, 'slider');
            app.GustSwitch.ValueChangedFcn = createCallbackFcn(app, @GustSwitchValueChanged, true);
            app.GustSwitch.Value = 'On';
            app.GustWidthEditFieldLabel = uilabel(app.GustPanel);
            app.GustWidthEditFieldLabel.Text = 'Gust Width';
            app.GustWidthEditField = uieditfield(app.GustPanel, 'numeric');
            app.GustWidthEditField.ValueChangedFcn = createCallbackFcn(app, @GustWidthEditFieldValueChanged, true);
            app.GustWidthEditField.Value = 0.17;
            app.GustTypeDropDownLabel = uilabel(app.GustPanel);
            app.GustTypeDropDownLabel.Text = 'Gust Type';
            app.GustTypeDropDown = uidropdown(app.GustPanel);
            app.GustTypeDropDown.Items = {'Top-hat', 'Sine-square', 'Trapezoid'};
            app.GustTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GustTypeDropDownValueChanged, true);
            app.GustTypeDropDown.Value = 'Trapezoid';
            app.InitialGustPositionmEditFieldLabel = uilabel(app.GustPanel);
            app.InitialGustPositionmEditFieldLabel.Text = 'Initial Gust Position,m';
            app.InitialGustPositionmEditField = uieditfield(app.GustPanel, 'numeric');
            app.InitialGustPositionmEditField.ValueChangedFcn = createCallbackFcn(app, @InitialGustPositionmEditFieldValueChanged, true);
            app.GREditFieldLabel = uilabel(app.GustPanel);
            app.GREditFieldLabel.Text = 'GR';
            app.GREditField = uieditfield(app.GustPanel, 'numeric');
            app.GREditField.ValueChangedFcn = createCallbackFcn(app, @GREditFieldValueChanged, true);
            app.GREditField.Value = 1;
            app.RiseSlopemsmEditFieldLabel = uilabel(app.GustPanel);
            app.RiseSlopemsmEditFieldLabel.Text = 'Rise Slope, ((m/s)/m)';
            app.RiseSlopemsmEditField = uieditfield(app.GustPanel, 'numeric');
            app.RiseSlopemsmEditField.ValueChangedFcn = createCallbackFcn(app, @RiseSlopemsmEditFieldValueChanged, true);
            app.RiseSlopemsmEditField.Value = 2.286;
            app.RiseSlopemsmEditFieldLabel_2 = uilabel(app.GustPanel);
            app.RiseSlopemsmEditFieldLabel_2.Text = '(Trapezoid)';
            app.AnimationPlotaxisPanel = uipanel(app.UIFigure);
            app.AnimationPlotaxisPanel.Title = 'Animation Plot axis';
            app.X_minmEditFieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.X_minmEditFieldLabel.Text = 'X_min, m';
            app.X_minmEditField = uieditfield(app.AnimationPlotaxisPanel, 'numeric');
            app.X_minmEditField.ValueChangedFcn = createCallbackFcn(app, @X_minmEditFieldValueChanged, true);
            app.X_minmEditField.Value = -0.2;
            app.Y_minmEditFieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.Y_minmEditFieldLabel.Text = 'Y_min, m';
            app.Y_minmEditField = uieditfield(app.AnimationPlotaxisPanel, 'numeric');
            app.Y_minmEditField.ValueChangedFcn = createCallbackFcn(app, @Y_minmEditFieldValueChanged, true);
            app.Y_minmEditField.Value = -0.15;
            app.FlowfieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.FlowfieldLabel.Text = 'Flowfield';
            app.minEditFieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.AnimationPlotaxisPanel, 'numeric');
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @minEditFieldValueChanged, true);
            app.minEditField.Value = -1;
            app.X_maxmEditFieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.X_maxmEditFieldLabel.Text = 'X_max, m';
            app.X_maxmEditField = uieditfield(app.AnimationPlotaxisPanel, 'numeric');
            app.X_maxmEditField.ValueChangedFcn = createCallbackFcn(app, @X_maxmEditFieldValueChanged, true);
            app.X_maxmEditField.Value = 0.5;
            app.Y_maxmEditFieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.Y_maxmEditFieldLabel.Text = 'Y_max, m';
            app.Y_maxmEditField = uieditfield(app.AnimationPlotaxisPanel, 'numeric');
            app.Y_maxmEditField.ValueChangedFcn = createCallbackFcn(app, @Y_maxmEditFieldValueChanged, true);
            app.Y_maxmEditField.Value = 0.15;
            app.LiftCoefficientLabel = uilabel(app.AnimationPlotaxisPanel);
            app.LiftCoefficientLabel.Text = 'Lift Coefficient';
            app.maxEditFieldLabel = uilabel(app.AnimationPlotaxisPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.AnimationPlotaxisPanel, 'numeric');
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @maxEditFieldValueChanged, true);
            app.maxEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end